classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure        matlab.ui.Figure
        Menu_5          matlab.ui.container.Menu
        Menu_2          matlab.ui.container.Menu
        Menu_9          matlab.ui.container.Menu
        Menu_10         matlab.ui.container.Menu
        Menu_4          matlab.ui.container.Menu
        Menu_3          matlab.ui.container.Menu
        Menu_6          matlab.ui.container.Menu
        Menu_7          matlab.ui.container.Menu
        Menu_11         matlab.ui.container.Menu
        Menu_12         matlab.ui.container.Menu
        Menu_13         matlab.ui.container.Menu
        PearsonMenu     matlab.ui.container.Menu
        MiCMenu         matlab.ui.container.Menu
        Menu_16         matlab.ui.container.Menu
        SpearmanMenu_2  matlab.ui.container.Menu
        Menu_14         matlab.ui.container.Menu
        Menu_15         matlab.ui.container.Menu
        PearsonMenu_2   matlab.ui.container.Menu
        SpearmanMenu    matlab.ui.container.Menu
        KendallMenu     matlab.ui.container.Menu
        MiCMenu_2       matlab.ui.container.Menu
        PCAMenu         matlab.ui.container.Menu
        PCoAMenu        matlab.ui.container.Menu
        bcMenu          matlab.ui.container.Menu
        RDAMenu         matlab.ui.container.Menu
        LDAMenu         matlab.ui.container.Menu
        Menu_17         matlab.ui.container.Menu
        Menu_18         matlab.ui.container.Menu
        Menu_19         matlab.ui.container.Menu
        Label           matlab.ui.control.Label
        EditField       matlab.ui.control.NumericEditField
        Button          matlab.ui.control.Button
        TabGroup        matlab.ui.container.TabGroup
        Tab             matlab.ui.container.Tab
        Button_2        matlab.ui.control.Button
    end

   properties (Access = private)
       DialogApp % Dialog box app
   end
    properties (Access = public)
        Ax
        Ay
        Az1
        Az
        depth
        Tl
        iGra
        % Description
    end

    methods (Access = public)
    
       function updateplot(app,sz,c) 
        % Process sz and c 
    
       end
        
    end


    methods (Access = private)

        % Menu selected function: Menu_2
        function Menu_2Selected(app, event)
            
              
        end

        % Menu selected function: Menu_3
        function Menu_3Selected(app, event)
           %热图           
           a=app.Ax;
            b=app.Ay;
            c=app.Az;
            figure(2)
            pcolor(a,b,c);shading interp%伪彩色图
        end

        % Menu selected function: Menu_6
        function Menu_6Selected(app, event)
            %等高线图
            a=app.Ax;
            b=app.Ay;
            c=app.Az;
            figure(2)
            contourf(a,b,c)
        end

        % Menu selected function: Menu_7
        function Menu_7Selected(app, event)
           %三维地形
            a=app.Ax;
            b=app.Ay;
            c=app.Az;
            figure(2)
            surf(a,b,c);shading interp ;rotate3d on;
        end

        % Callback function
        function UITableCellEdit(app, event)
            app.depth=app.EditField.Value;
        end

        % Value changed function: EditField
        function EditFieldValueChanged(app, event)
            value = app.EditField.Value;        
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            aTop=app.EditField.Value;
            Z=app.Az1;
            Z(Z>aTop)=aTop;
            app.Az=Z;
        end

        % Menu selected function: Menu_9
        function Menu_9Selected(app, event)
            [filename,pathname]=uigetfile({'*.txt';'*.xyz'});
            %U=textread('C:\Users\elen\Documents\MATLAB\abc.txt');
            U=textread([pathname,filename]);
            x=U(:,1);
            y=U(:,2);
            z=U(:,3);
            a1=x(2)-x(1);a=fix((max(x)-min(x))/a1+1);
            b=fix(length(y)/a);
            A=1:a;B=1:b;
            [X,Y]=meshgrid(B,A);Z=reshape(z,[a,b]);
            app.Ax=X;
            app.Ay=Y;
            app.Az=Z;app.Az1=Z;

          % figure(1)
          % plot([1:10],sin([1:10]))
          
            
              Ut=app.Tab;
              t1 = uitable(Ut,'Data',U);
        end

        % Menu selected function: Menu_10
        function Menu_10Selected(app, event)
            [filename,pathname]=uigetfile({'*.xlsx';'*.xls'});
             [N,T,~]=xlsread([pathname,filename]);
             app.depth=N;
             Ut=app.Tab;
             app.Tl=T;
              t1 = uitable(Ut,'Data',N);
        end

        % Menu selected function: Menu_11
        function Menu_11Selected(app, event)
            H=app.depth;T=app.Tl;[a,b]=size(T);
            xl=T(2:a,1);yl=T(1,2:b);
            heatmap(yl,xl,H);
        end

        % Menu selected function: Menu_12
        function Menu_12Selected(app, event)
            N=app.depth;
            T=app.Tl;
            [a,b]=size(T);
            xl=T(2:a,1);yl=T(1,2:b);
            clustergram(N,'RowLabels',xl,'ColumnLabels',yl)
        end

        % Callback function
        function Menu_13Selected(app, event)
          
        end

        % Menu selected function: PearsonMenu
        function PearsonMenuSelected(app, event)
            N=app.depth;
            T=app.Tl;
            aTop=app.EditField.Value;
            app.iGra=[];
            [a,b]=size(N);
            l=T(2:a+1,1);
            cN=corr(N');
            [~,p]=corrcoef(N');
            %cN=mic(N);
            data=[];
            A=a^2;
            cN1=abs(cN);cN1=imbinarize(cN1,aTop);
            p=imbinarize(p,0.05) ;
            p=~p;
            Gra=p&cN1;
           
%              for i=1:a
%                 for j=1:a
%                     if abs(cN(i,j))>aTop&abs(cN(i,j))<1&p(i,j)<0.05
%                         Gra(i,j)=1;
%                     else 
%                         Gra(i,j)=0;
%                     end
%                 end
%              end
             
             app.iGra=Gra;
            Graphy=Gra;
         
            
            for i=1:a
                for j=1:i
                data{(i-1)*a+j,1}=l{j};
                   if i==j
                    data{(i-1)*a+j,2}=0;data{(i-1)*a+j,4}=1;
                   else
                    data{(i-1)*a+j,2}=cN(i,j);
                    data{(i-1)*a+j,4}=p(i,j);
                   end
                data{(i-1)*a+j,3}=l{i};
               end
            end
           D={};j=0;tet=[];

for i=1:A
if abs(data{i,2})>aTop&abs(data{i,2})<1&data{i,4}<0.05
j=j+1;
D{j,1}=data{i,1};D{j,3}=data{i,3};tet(j)=data{i,2};
if data{i,2}<0 
    D{j,2}=['np'];
else
    D{j,2}=['pp'];

end
end
end
mycell=D;
[nrows,ncols]= size(mycell);
filename = 'E:\NET1.sif';
fid = fopen(filename, 'w');
for row=1:nrows
    fprintf(fid, '%s\t%s\t%s\n', mycell{row,1:3});
end
fclose(fid);
 Graphy1=graph(Graphy,l);
 plot(Graphy1);
        end

        % Menu selected function: MiCMenu
        function MiCMenuSelected(app, event)
            N=app.depth;
            T=app.Tl;
            aTop=app.EditField.Value;           
            [a,b]=size(N);
            l=T(2:a+1,1);
            cN=mic(N);
            cN=cN+cN';
            data=[];
            A=a^2;
            app.iGra=[];
             for i=1:a
                for j=1:a
                    if abs(cN(i,j))>aTop&abs(cN(i,j))<=1
                        Gra(i,j)=1;
                    else 
                        Gra(i,j)=0;
                    end
                end
             end
             Gra1=Gra;
            app.iGra=Gra1;
             
            Graphy=Gra;
        
            
            for i=1:a
                for j=1:i
                data{(i-1)*a+j,1}=l{j};
                   if i==j
                    data{(i-1)*a+j,2}=0;
                   else
                    data{(i-1)*a+j,2}=cN(i,j);
                   end
                data{(i-1)*a+j,3}=l{i};
               end
            end
           D={};j=0;tet=[];

           app.iGra=Gra;
for i=1:A
if abs(data{i,2})>aTop&abs(data{i,2})<1
j=j+1;
D{j,1}=data{i,1};D{j,3}=data{i,3};tet(j)=data{i,2};
if data{i,2}<0 
    D{j,2}=['np'];
else
    D{j,2}=['pp'];

end
end
end
mycell=D;
[nrows,ncols]= size(mycell);
filename = 'E:\micNET1.sif';
fid = fopen(filename, 'w');
for row=1:nrows
    fprintf(fid, '%s\t%s\t%s\n', mycell{row,1:3});
end
fclose(fid);
 Graphy1=graph(Graphy,l);
 h=plot(Graphy1,'layout','force');
 
        end

        % Menu selected function: PearsonMenu_2
        function PearsonMenu_2Selected(app, event)
            H=app.depth;T=app.Tl;[a,b]=size(T);
            cH=corr(H');
            xl=T(2:a,1);yl=T(1,2:b);
            heatmap(xl,xl,cH);
        end

        % Menu selected function: SpearmanMenu
        function SpearmanMenuSelected(app, event)
            H=app.depth;T=app.Tl;[a,b]=size(T);
            cH=corr(H','type','Spearman');
            xl=T(2:a,1);yl=T(1,2:b);
            heatmap(xl,xl,cH);
        end

        % Menu selected function: KendallMenu
        function KendallMenuSelected(app, event)
            H=app.depth;T=app.Tl;[a,b]=size(T);
            cH=corr(H','type','Kendall');
            xl=T(2:a,1);yl=T(1,2:b);
            heatmap(xl,xl,cH);
        end

        % Menu selected function: MiCMenu_2
        function MiCMenu_2Selected(app, event)
            H=app.depth;T=app.Tl;[a,b]=size(T);
            cH=mic(H);
            xl=T(2:a,1);yl=T(1,2:b);
            heatmap(xl,xl,cH);            
        end

        % Callback function
        function PCAMenuSelected(app, event)
             H=app.depth;T=app.Tl;[a,b]=size(T);
             r=f_pca(H);
            rPca=r.evects;
            f_biplotPca2(rPca)
        end

        % Menu selected function: bcMenu
        function bcMenuSelected(app, event)
             H=app.depth;T=app.Tl;[a,b]=size(T);
             sl=T(2:a,1);
            dis=f_braycurtis(H');
            rPco=f_pcoa(dis);
            f_pcoaPlot(rPco,sl);
        end

        % Menu selected function: RDAMenu
        function RDAMenuSelected(app, event)
                 [filename,pathname]=uigetfile({'*.xlsx';'*.xls'});
                 [ENv,TEnv,~]=xlsread([pathname,filename]);
                        aTop=app.EditField.Value;  aTop=aTop*10;
                 x=app.depth;T=app.Tl;[a,b]=size(T);
                 [ae,be]=size(TEnv);
                 xl=T(2:a,1);
                 yl=TEnv(2:ae,1);
                 sl=TEnv(1,2:be);
                 y=ENv;
                 rRda=f_rda(x',y);
                 f_trdaPlot(rRda,x',aTop,[aTop,aTop],0,xl,sl,'tex',0,yl) 
        end

        % Menu selected function: LDAMenu
        function LDAMenuSelected(app, event)
            H=app.depth;T=app.Tl;
            H=H';T=T';%转置物种数据
            [a,b]=size(T);            
            aTop=app.EditField.Value;    
            w1=H(1:aTop,:);w2=H(aTop:end,:);
            lDA=FisherLDA(w1,w2);
             c=categorical(T(1,2:b));
%              lDA1=lDA;lDA2=lDA;
%              lDA1(find(lDA1)>0)=0;
%              lDA2(find(lDA2)<0)=0;
            bar(c,lDA);
            f = figure;
           t = uitable(f);
          % d = {lDA};
           t.Data = lDA;
        end

        % Menu selected function: Menu_16
        function Menu_16Selected(app, event)
            N=app.depth;
            T=app.Tl;
            aTop=app.EditField.Value;
            
            [a,b]=size(N);
            l=T(2:a+1,1);
            cP=corr(N');cN=mic(N);
            %cN=mic(N);
            data=[];
            A=a^2;
            for i=1:a
                for j=1:i
                data{(i-1)*a+j,1}=l{j};
                   if i==j
                    data{(i-1)*a+j,2}=0;
                    data{(i-1)*a+j,3}=0;
                   else
                    data{(i-1)*a+j,2}=cP(i,j);
                     data{(i-1)*a+j,3}=cN(i,j);
                   end
                data{(i-1)*a+j,4}=l{i};
               end
            end

           D={};j=0;tet=[];


           for i=1:A
            if abs(data{i,2})>aTop&abs(data{i,2})<1
                j=j+1;
                D{j,1}=data{i,1};D{j,3}=data{i,4};tet(j)=data{i,2};
                    if data{i,2}<0 
                         D{j,2}=['np'];
                     else
                D{j,2}=['pp'];

            end
            end
           end
           for i=1:A
            if abs(data{i,3})>aTop&abs(data{i,3})<1&abs(data{i,2})<aTop
                j=j+1;
                D{j,1}=data{i,1};D{j,3}=data{i,4};tet(j)=data{i,2};
                 D{j,2}=['sp'];
                           
            end
           end
           
mycell=D;
[nrows,ncols]= size(mycell);
filename = 'E:\micNET1.sif';
fid = fopen(filename, 'w');
for row=1:nrows
    fprintf(fid, '%s\t%s\t%s\n', mycell{row,1:3});
end
fclose(fid);
           

mycell=D;
[nrows,ncols]= size(mycell);
filename = 'E:\mPNET1.sif';
fid = fopen(filename, 'w');
for row=1:nrows
    fprintf(fid, '%s\t%s\t%s\n', mycell{row,1:3});
end
fclose(fid);

        end

        % Menu selected function: SpearmanMenu_2
        function SpearmanMenu_2Selected(app, event)
            N=app.depth;
            T=app.Tl;
            aTop=app.EditField.Value;
            app.iGra=[];
            [a,b]=size(N);
            l=T(2:a+1,1);
            [cN,p]=corr(N','type','Spearman');
            %[~,p]=corrcoef(N');
            %cN=mic(N);
            data=[];
            A=a^2;
         
            for i=1:a
                for j=1:a
                    if abs(cN(i,j))>aTop&abs(cN(i,j))<1&p(i,j)<0.05
                        Gra(i,j)=1;
                    else 
                        Gra(i,j)=0;
                    end
                end
            end
          
            app.iGra=Gra;
            Graphy1=Gra;
            ll=l;
            cNx=cN;
            aa=sum(Gra);
            aa=find(aa==0);
            cN(aa,:)=[];
            cN(:,aa)=[];
            [a,~]=size(cN);
            p(aa,:)=[];
            p(:,aa)=[];
            l(aa)=[];
            
            for i=1:a
                for j=1:i
                data{(i-1)*a+j,1}=l{j};
                   if i==j
                    data{(i-1)*a+j,2}=0;data{(i-1)*a+j,4}=1;
                   else
                    data{(i-1)*a+j,2}=cN(i,j);
                    data{(i-1)*a+j,4}=p(i,j);
                   end
                data{(i-1)*a+j,3}=l{i};
               end
            end
           D={};j=0;tet=[];
           A=a.^2;

for i=1:A
if abs(data{i,2})>aTop&abs(data{i,2})<1&data{i,4}<0.05
j=j+1;
D{j,1}=data{i,1};D{j,3}=data{i,3};tet(j)=data{i,2};
if data{i,2}<0 
    D{j,2}=['np'];
else
    D{j,2}=['pp'];

end
end
end
mycell=D;
[nrows,ncols]= size(mycell);
filename = 'E:\NET1.sif';
fid = fopen(filename, 'w');
for row=1:nrows
    fprintf(fid, '%s\t%s\t%s\n', mycell{row,1:3});
end
fclose(fid);
 Graphy1=graph(Gra,ll);
 plot(Graphy1,'layout','force');

        end

        % Callback function
        function Menu_19Selected(app, event)
            Gra=app.iGra;
            
        end

        % Menu selected function: Menu_18
        function Menu_18Selected(app, event)
            Gra=app.iGra;
           % Gra(Gra==0)=[];
          %  [node,~]=size(Gra);
           % del_l=all(a==0,2);
        %%   a(all(a==0,2),:) = [];
            %去掉矩阵中的全0列
        %%    a(all(a==0,1),:) = []; 
            

            dg=sum(Gra);[~,node]=size(dg);
            dg(dg==0)=[];
            xbins=max(dg);
           [a1,b1]=hist(dg,xbins);
            edge=sum(dg);
            edge=edge./2;
            %cf = 'a^x';
            dEN=edge/(node*(node-1));
          % p=fittype('a*x.^b','independent','x');
          % [fa,~]=fit(a1,b1,p);
           %f='a.^x';
           f = figure;
           t = uitable(f);
           d = {'Node',node;'Edge',edge;'density',dEN};
           t.Data = d;
           
            figure
           histogram(dg);
         %  fy=fa.a*a1.^fa.b;
          % plot(x,fy);
        
%             fIT=fittype('a.*x');
%             a2=log(a1);b2=log(b1);
%             fI=fit(a2',b2',fIT);
%             plot(fI,a2',b2');
%   
        end

        % Callback function
        function Menu_19Selected2(app, event)
   app.OptionsButton.Enable = 'off';

    % Get szvalue and cvalue
    % ....
    
    % Call dialog box with input values
    app.DialogApp = DialogAppExample(app,szvalue,cvalue);
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            app.Button_2.Enable = 'off';
            % Open the options dialog and pass inputs
            app.app1netplot = app1netplot();
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
%              % Delete both apps
%              delete(app.app1netplot)
              delete(app)   
        end

        % Menu selected function: Menu_19
        function Menu_19Selected3(app, event)
             N=app.depth;
             [a,b]=size(N);
             app.iGra=[];
             sH=sum(N);
             for i=1:b
            shannon(i)=sum(N(:,i)./sH(i).*N(:,i)./log2(sH(i)));
             end
             %bar(shannon)
             for i=1:b
             simpson(i)=1-sum((N(:,i)./sH(i)).^2);
             end
             
             for i=1:b
                F0=sum(N(:,i)==0);
                 F1=sum(N(:,i)==1);
                 F2=sum(N(:,i)==2);
                 chao1(i)=a-F0+(F1.^2./(2*F2.^2));
             end
             bar(chao1)
%              f = figure;
%              t = uitable(f);
%                d = {'Shannon',shannon;'simpson',simpson};
%                t.Data = d;
               
        end

        % Menu selected function: PCAMenu
        function PCAMenuSelected2(app, event)
             H=app.depth;T=app.Tl;[a,b]=size(T);
             sl=T(2:a,1);
             [coeff,score,latent] = pca(H);
             biplot(coeff(:,1:2),'score',score(:,1:2));
        %    biplot(coeff(:,1:2),'score',score(:,1:2),'varlabels',sl);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.Menu_5 = uimenu(app.UIFigure);
            app.Menu_5.Text = '开始';
            app.Menu_2 = uimenu(app.Menu_5);
            app.Menu_2.MenuSelectedFcn = createCallbackFcn(app, @Menu_2Selected, true);
            app.Menu_2.Text = '数据';
            app.Menu_9 = uimenu(app.Menu_2);
            app.Menu_9.MenuSelectedFcn = createCallbackFcn(app, @Menu_9Selected, true);
            app.Menu_9.Text = '地形数据';
            app.Menu_10 = uimenu(app.Menu_2);
            app.Menu_10.MenuSelectedFcn = createCallbackFcn(app, @Menu_10Selected, true);
            app.Menu_10.Text = '表格数据';
            app.Menu_4 = uimenu(app.UIFigure);
            app.Menu_4.Text = '编辑';
            app.Menu_3 = uimenu(app.Menu_4);
            app.Menu_3.MenuSelectedFcn = createCallbackFcn(app, @Menu_3Selected, true);
            app.Menu_3.Text = '地形热图';
            app.Menu_6 = uimenu(app.Menu_4);
            app.Menu_6.MenuSelectedFcn = createCallbackFcn(app, @Menu_6Selected, true);
            app.Menu_6.Text = '地形等高线图';
            app.Menu_7 = uimenu(app.Menu_4);
            app.Menu_7.MenuSelectedFcn = createCallbackFcn(app, @Menu_7Selected, true);
            app.Menu_7.Text = '三维地形图';
            app.Menu_11 = uimenu(app.Menu_4);
            app.Menu_11.MenuSelectedFcn = createCallbackFcn(app, @Menu_11Selected, true);
            app.Menu_11.Separator = 'on';
            app.Menu_11.Text = '热图';
            app.Menu_12 = uimenu(app.Menu_4);
            app.Menu_12.MenuSelectedFcn = createCallbackFcn(app, @Menu_12Selected, true);
            app.Menu_12.Text = '聚类热图';
            app.Menu_13 = uimenu(app.Menu_4);
            app.Menu_13.Text = '共现网络';
            app.PearsonMenu = uimenu(app.Menu_13);
            app.PearsonMenu.MenuSelectedFcn = createCallbackFcn(app, @PearsonMenuSelected, true);
            app.PearsonMenu.Text = 'Pearson';
            app.MiCMenu = uimenu(app.Menu_13);
            app.MiCMenu.MenuSelectedFcn = createCallbackFcn(app, @MiCMenuSelected, true);
            app.MiCMenu.Text = 'MiC';
            app.Menu_16 = uimenu(app.Menu_13);
            app.Menu_16.MenuSelectedFcn = createCallbackFcn(app, @Menu_16Selected, true);
            app.Menu_16.Text = '线性-非线性';
            app.SpearmanMenu_2 = uimenu(app.Menu_13);
            app.SpearmanMenu_2.MenuSelectedFcn = createCallbackFcn(app, @SpearmanMenu_2Selected, true);
            app.SpearmanMenu_2.Text = 'Spearman';
            app.Menu_14 = uimenu(app.UIFigure);
            app.Menu_14.Text = '计算';
            app.Menu_15 = uimenu(app.Menu_14);
            app.Menu_15.Text = '相关热图';
            app.PearsonMenu_2 = uimenu(app.Menu_15);
            app.PearsonMenu_2.MenuSelectedFcn = createCallbackFcn(app, @PearsonMenu_2Selected, true);
            app.PearsonMenu_2.Text = 'Pearson';
            app.SpearmanMenu = uimenu(app.Menu_15);
            app.SpearmanMenu.MenuSelectedFcn = createCallbackFcn(app, @SpearmanMenuSelected, true);
            app.SpearmanMenu.Text = 'Spearman';
            app.KendallMenu = uimenu(app.Menu_15);
            app.KendallMenu.MenuSelectedFcn = createCallbackFcn(app, @KendallMenuSelected, true);
            app.KendallMenu.Text = 'Kendall';
            app.MiCMenu_2 = uimenu(app.Menu_15);
            app.MiCMenu_2.MenuSelectedFcn = createCallbackFcn(app, @MiCMenu_2Selected, true);
            app.MiCMenu_2.Text = 'MiC';
            app.PCAMenu = uimenu(app.Menu_14);
            app.PCAMenu.MenuSelectedFcn = createCallbackFcn(app, @PCAMenuSelected2, true);
            app.PCAMenu.Text = 'PCA';
            app.PCoAMenu = uimenu(app.Menu_14);
            app.PCoAMenu.Text = 'PCoA';
            app.bcMenu = uimenu(app.PCoAMenu);
            app.bcMenu.MenuSelectedFcn = createCallbackFcn(app, @bcMenuSelected, true);
            app.bcMenu.Text = 'b-c';
            app.RDAMenu = uimenu(app.Menu_14);
            app.RDAMenu.MenuSelectedFcn = createCallbackFcn(app, @RDAMenuSelected, true);
            app.RDAMenu.Text = 'RDA';
            app.LDAMenu = uimenu(app.Menu_14);
            app.LDAMenu.MenuSelectedFcn = createCallbackFcn(app, @LDAMenuSelected, true);
            app.LDAMenu.Text = 'LDA';
            app.Menu_17 = uimenu(app.Menu_14);
            app.Menu_17.Separator = 'on';
            app.Menu_17.Text = '网络参数计算';
            app.Menu_18 = uimenu(app.Menu_17);
            app.Menu_18.MenuSelectedFcn = createCallbackFcn(app, @Menu_18Selected, true);
            app.Menu_18.Text = '拓扑参数计算';
            app.Menu_19 = uimenu(app.Menu_14);
            app.Menu_19.MenuSelectedFcn = createCallbackFcn(app, @Menu_19Selected3, true);
            app.Menu_19.Text = 'α多样性';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '阈值：';
            app.EditField = uieditfield(app.UIFigure, 'numeric');
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.EditField.Value = 0.9;
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '计算';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = 'Tab';
            app.Button_2 = uibutton(app.UIFigure, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '绘图';
        end
    end

    methods (Access = public)

        % Construct app
        function app = app1

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end